classdef ReadData_Collin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        ReadDataMenu       matlab.ui.container.Menu
        DifferentiateMenu  matlab.ui.container.Menu
        CreateRobotMenu    matlab.ui.container.Menu
        HardwareMenu       matlab.ui.container.Menu
        InfoTextArea       matlab.ui.control.TextArea
        InfoTextAreaLabel  matlab.ui.control.Label
        DataReadLamp       matlab.ui.control.Lamp
        DataReadLampLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data = []; % Data for display
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataReadLamp.Color = 'r';
        end

        % Menu selected function: ReadDataMenu
        function ReadDataMenuSelected(app, event)
            [fn pn] = uigetfile ('*.txt', 'Where is the data?');
            T = textread([pn fn]);
            plot(app.UIAxes, T(:,1), T(:,2))
            app.data = T;
            app.DataReadLamp.Color = 'g';
            app.InfoTextArea.Value = ['Data Read Successfully' newline newline 'Hooray!'];

        end

        % Menu selected function: DifferentiateMenu
        function DifferentiateMenuSelected(app, event)
            if ~isempty(app.data)
                diffData = [0; diff(app.data(:,2))];
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.data(:, 1), diffData, 'g')
                app.DataReadLamp.Color = 'g';
                app.InfoTextArea.Value = ['Data Differentiated Successfully' newline newline 'Hooray!'];
            else
                app.DataReadLamp.Color = 'r';
                app.InfoTextArea.Value = ['No data to differentiate' newline newline ':('];
            end
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobotMenuSelected(app, event)
            mdl_puma560;
            p560.teach()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadDataMenu = uimenu(app.FileMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadDataMenuSelected, true);
            app.ReadDataMenu.Text = 'Read Data';
            app.DifferentiateMenu = uimenu(app.FileMenu);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferentiateMenuSelected, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.CreateRobotMenu = uimenu(app.FileMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobotMenuSelected, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataReadLampLabel = uilabel(app.UIFigure);
            app.DataReadLampLabel.Text = 'Data Read?';
            app.DataReadLamp = uilamp(app.UIFigure);
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReadData_Collin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end