classdef read_and_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu_2         matlab.ui.container.Menu
        ReadDataMenu_2     matlab.ui.container.Menu
        DifferentiateMenu  matlab.ui.container.Menu
        Info               matlab.ui.container.Menu
        HardwareMenu       matlab.ui.container.Menu
        InitArduinoMenu    matlab.ui.container.Menu
        TestArduinoMenu    matlab.ui.container.Menu
        InfoTextArea       matlab.ui.control.TextArea
        InfoTextAreaLabel  matlab.ui.control.Label
        DataReadLamp       matlab.ui.control.Lamp
        DataReadLampLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataRead = false;
        Data = [] % Description
        DiffData =[] % Description
        arduino_hardware =[];
        arduino_initialized = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ReadDataMenu_2
        function ReadData(app, event)
       
            [fn pn] = uigetfile ('*.txt', 'Where is the data?');
            filename = [pn fn];
            app.Data = textread(filename);
            plot (app.UIAxes, app.Data(:, 1), app.Data (:, 2));
            app.DataRead = true;
            app.DataReadLamp.Color = [0.0 1.0 0.0];
            app.InfoTextArea.Value = 'Data has been read.'
        end

        % Menu selected function: DifferentiateMenu
        function Differentiate(app, event)
            if (app.DataRead)
                app.DiffData = app.Data;
                app.DiffData(1:759,2) = diff (app.Data (:, 2));
                hold(app.UIAxes, 'on');
                plot (app.UIAxes, app.DiffData(:,1), app.DiffData (:, 2), 'g');
                app.InfoTextArea.Value = 'Green is the differential';
            else
                app.InfoTextArea.Value = 'Read the data first';
            end
        end

        % Menu selected function: InitArduinoMenu
        function Initialize(app, event)
            try
                app.arduino_hardware = arduino();
            catch
              
                msgbox ('error on hardware initialize');
                return;
            end
            app.arduino_initialized = true;
            msgbox ('Ardunio initialized');
        end

        % Menu selected function: TestArduinoMenu
        function TestArduino(app, event)
            if (app.arduino_initialized)
                app.arduino_hardware.writeDigitalPin("D13", 1)
            end
        end

        % Menu selected function: Info
        function CreateRobot(app, event)
            mdl_puma560;
            axes(app.UIAxes);
            p560.plot( [0 0 0 0 0 0]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu_2 = uimenu(app.UIFigure);
            app.FileMenu_2.Text = 'File';
            app.ReadDataMenu_2 = uimenu(app.FileMenu_2);
            app.ReadDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ReadData, true);
            app.ReadDataMenu_2.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu_2);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @Differentiate, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.Info = uimenu(app.FileMenu_2);
            app.Info.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.Info.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.InitArduinoMenu = uimenu(app.HardwareMenu);
            app.InitArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitArduinoMenu.Text = 'InitArduino';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @TestArduino, true);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PlotArea')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataReadLampLabel = uilabel(app.UIFigure);
            app.DataReadLampLabel.Text = 'Data Read?';
            app.DataReadLamp = uilamp(app.UIFigure);
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = read_and_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end